classdef NonWaistQuadrupedRobotFKUIforFast < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RobotPhysicalParameterSetPanel  matlab.ui.container.Panel
        BLegLLink                       matlab.ui.control.NumericEditField
        BLegLLinkEditFieldLabel         matlab.ui.control.Label
        BLegULink2                      matlab.ui.control.NumericEditField
        BLegULink2EditFieldLabel        matlab.ui.control.Label
        BLegULink1                      matlab.ui.control.NumericEditField
        BLegULink1EditFieldLabel        matlab.ui.control.Label
        BLegSLink                       matlab.ui.control.NumericEditField
        BLegSLinkEditFieldLabel         matlab.ui.control.Label
        FLegLLink                       matlab.ui.control.NumericEditField
        FLegLLinkEditFieldLabel         matlab.ui.control.Label
        FLegULink2                      matlab.ui.control.NumericEditField
        FLegULink2EditFieldLabel        matlab.ui.control.Label
        FLegULink1                      matlab.ui.control.NumericEditField
        FLegULink1EditFieldLabel        matlab.ui.control.Label
        FLegSLink                       matlab.ui.control.NumericEditField
        FLegSLinkLabel                  matlab.ui.control.Label
        BodyWidth                       matlab.ui.control.NumericEditField
        BodyWidthEditFieldLabel         matlab.ui.control.Label
        BodyLength                      matlab.ui.control.NumericEditField
        BodyLengthEditFieldLabel        matlab.ui.control.Label
        LBFootPanel                     matlab.ui.container.Panel
        LBFY                            matlab.ui.control.NumericEditField
        YEditField_5Label               matlab.ui.control.Label
        LBFZ                            matlab.ui.control.NumericEditField
        ZEditField_5Label               matlab.ui.control.Label
        LBFX                            matlab.ui.control.NumericEditField
        XEditField_5Label               matlab.ui.control.Label
        RBFootPanel                     matlab.ui.container.Panel
        RBFY                            matlab.ui.control.NumericEditField
        YEditField_4Label               matlab.ui.control.Label
        RBFZ                            matlab.ui.control.NumericEditField
        ZEditField_4Label               matlab.ui.control.Label
        RBFX                            matlab.ui.control.NumericEditField
        XEditField_4Label               matlab.ui.control.Label
        LFFootPanel                     matlab.ui.container.Panel
        LFFY                            matlab.ui.control.NumericEditField
        YEditField_3Label               matlab.ui.control.Label
        LFFZ                            matlab.ui.control.NumericEditField
        ZEditField_3Label               matlab.ui.control.Label
        LFFX                            matlab.ui.control.NumericEditField
        XEditField_3Label               matlab.ui.control.Label
        RFFootPanel                     matlab.ui.container.Panel
        RFFY                            matlab.ui.control.NumericEditField
        YEditField_2Label               matlab.ui.control.Label
        RFFZ                            matlab.ui.control.NumericEditField
        ZEditField_2Label               matlab.ui.control.Label
        RFFX                            matlab.ui.control.NumericEditField
        XEditField_2Label               matlab.ui.control.Label
        LeftBackLegActuatorControlPanel  matlab.ui.container.Panel
        LBLJoint                        matlab.ui.control.Slider
        LowerSlider_4Label              matlab.ui.control.Label
        LBUJoint                        matlab.ui.control.Slider
        UpperSlider_4Label              matlab.ui.control.Label
        LBSJoint                        matlab.ui.control.Slider
        ShoulderSlider_4Label           matlab.ui.control.Label
        RightBackLegActuatorControlPanel  matlab.ui.container.Panel
        RBLJoint                        matlab.ui.control.Slider
        LowerSlider_3Label              matlab.ui.control.Label
        RBUJoint                        matlab.ui.control.Slider
        UpperSlider_3Label              matlab.ui.control.Label
        RBSJoint                        matlab.ui.control.Slider
        ShoulderSlider_3Label           matlab.ui.control.Label
        LeftFrontLegActuatorControlPanel  matlab.ui.container.Panel
        LFLJoint                        matlab.ui.control.Slider
        LowerSlider_2Label              matlab.ui.control.Label
        LFUJoint                        matlab.ui.control.Slider
        UpperSlider_2Label              matlab.ui.control.Label
        LFSJoint                        matlab.ui.control.Slider
        ShoulderSlider_2Label           matlab.ui.control.Label
        RightFrontLegActuatorControlPanel  matlab.ui.container.Panel
        RFLJoint                        matlab.ui.control.Slider
        LowerSliderLabel                matlab.ui.control.Label
        RFUJoint                        matlab.ui.control.Slider
        UpperSliderLabel                matlab.ui.control.Label
        RFSJoint                        matlab.ui.control.Slider
        ShoulderSliderLabel             matlab.ui.control.Label
        PositionofCenterofBodyPanel     matlab.ui.container.Panel
        CoBY                            matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        CoBZ                            matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        CoBX                            matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        CalculationStartButton          matlab.ui.control.Button
        NonWaistQuadrupedRobotPlot      matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function RobotPlot = RobotPlot(app, BodyLength, BodyWidth, FLegSLink, FLegULink1, FLegULink2, FLegLLink, BLegSLink, BLegULink1, BLegULink2, BLegLLink, RFSJoint, RFUJoint, RFLJoint, LFSJoint, LFUJoint, LFLJoint, RBSJoint, RBUJoint, RBLJoint, LBSJoint, LBUJoint, LBLJoint)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Presetting
            function Rotx = Rotx(omega)
                Rotx=[1  0   0
                0   cos(omega)  -sin(omega)
                0   sin(omega)  cos(omega)];
            end
            
            function Roty = Roty(phi)
                Roty=[cos(phi) 0   sin(phi)
                0   1   0
                -sin(phi)   0   cos(phi)];
            end
    
            function Rotz = Rotz(psi)
                Rotz=[cos(psi) -sin(psi)   0
                sin(psi)    cos(psi)    0
                0   0   1];
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Body Kinematics & Plot
            % Global to Body
            angleg2m_x=sym(pi)/2;
            angleg2m_y=0;
            angleg2m_z=0;
            pg2m=[0 0 0].';
            Rotg2m=Rotx(angleg2m_x)*Roty(angleg2m_y)*Rotz(angleg2m_z);
            Tg2m=HomogeneousTransform(Rotg2m, pg2m, 1);

            % Center of Body Value Printing
            app.CoBX.Value=double(Tg2m(1,4));
            app.CoBY.Value=double(Tg2m(2,4));
            app.CoBZ.Value=double(Tg2m(3,4));
            
            % Each Shoulder position according to Body
            pm2RFS=[BodyLength/2 0 BodyWidth/2 1].';
            pm2LFS=[BodyLength/2 0 -BodyWidth/2 1].';
            pm2RBS=[-BodyLength/2 0 BodyWidth/2 1].';
            pm2LBS=[-BodyLength/2 0 -BodyWidth/2 1].';

            % Each Shoulder position according to Global
            pg2RFS = Tg2m*pm2RFS;
            pg2LFS = Tg2m*pm2LFS;
            pg2RBS = Tg2m*pm2RBS;
            pg2LBS = Tg2m*pm2LBS;

            % Plot Body
            p = plot3(app.NonWaistQuadrupedRobotPlot, [pg2RFS(1,1), pg2LFS(1,1), pg2LBS(1,1), pg2RBS(1,1), pg2RFS(1,1)], [pg2RFS(2,1), pg2LFS(2,1), pg2LBS(2,1), pg2RBS(2,1), pg2RFS(2,1)], [pg2RFS(3,1), pg2LFS(3,1), pg2LBS(3,1), pg2RBS(3,1), pg2RFS(3,1)],'-o','LineWidth',3);
            hold(app.NonWaistQuadrupedRobotPlot,'on');
            p.MarkerFaceColor = [1 0.5 0];
            p.MarkerSize = 8;
            p.MarkerEdgeColor = [1 0.5 0];
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Right Front Leg Kinematics & Plot
            % Right Front Foot position according to Body
            pm2RFU=[BodyLength/2+FLegSLink 0 BodyWidth/2 1].';
            pm2RFL1=pm2RFU+[0 -FLegULink1*sin(RFSJoint) FLegULink1*cos(RFSJoint) 1].';
            pm2RFL2=pm2RFL1+[FLegULink2*sin(RFUJoint) -FLegULink2*cos(RFSJoint)*cos(RFSJoint) -FLegULink2*sin(RFSJoint)*cos(RFUJoint) 1].';
            pm2RFF=pm2RFL2+[FLegLLink*sin(RFUJoint+RFLJoint) -FLegLLink*cos(RFSJoint)*cos(RFUJoint+RFLJoint) -FLegLLink*sin(RFSJoint)*cos(RFUJoint+RFLJoint) 1].';

            % Right Front Foot position according to Global
            pg2RFU = Tg2m*pm2RFU;
            pg2RFL1 = Tg2m*pm2RFL1;
            pg2RFL2 = Tg2m*pm2RFL2;
            pg2RFF = Tg2m*pm2RFF;
            
            % Right Front Foot Value Printing
            app.RFFX.Value=double(pm2RFF(1,1));
            app.RFFY.Value=double(pm2RFF(2,1));
            app.RFFZ.Value=double(pm2RFF(3,1));
            
            % Plot Right Front Leg
            p = plot3(app.NonWaistQuadrupedRobotPlot, [pg2RFS(1,1), pg2RFU(1,1), pg2RFL1(1,1), pg2RFL2(1,1), pg2RFF(1,1)], [pg2RFS(2,1), pg2RFU(2,1), pg2RFL1(2,1), pg2RFL2(2,1), pg2RFF(2,1)], [pg2RFS(3,1), pg2RFU(3,1), pg2RFL1(3,1), pg2RFL2(3,1), pg2RFF(3,1)],'-o','LineWidth',3);
            p.MarkerFaceColor = [1 0.5 0];
            p.MarkerSize = 8;
            p.MarkerEdgeColor = [1 0.5 0];
            %{
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Left Front Leg Kinematics & Plot
            % Left Front Foot position according to Body

            % Left Front Foot position according to Global

            % Left Front Foot Value Printing
            app.LFFX.Value=double(Tg24(1,4));
            app.LFFY.Value=double(Tg24(2,4));
            app.LFFZ.Value=double(Tg24(3,4));
            
            % Plot Left Front Leg
            p = plot3(app.NonWaistQuadrupedRobotPlot, [Tg2LFS(1,4), Tg22(1,4), pgLFL1(1,1), Tg23(1,4), Tg24(1,4)], [Tg2LFS(2,4), Tg22(2,4), pgLFL1(2,1), Tg23(2,4), Tg24(2,4)], [Tg2LFS(3,4), Tg22(3,4), pgLFL1(3,1), Tg23(3,4), Tg24(3,4)],'-o','LineWidth',3);
            p.MarkerFaceColor = [1 0.5 0];
            p.MarkerSize = 8;
            p.MarkerEdgeColor = [1 0.5 0];
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Right Back Leg Kinematics & Plot
            % Right Back Foot position according to Body

            % Right Back Foot position according to Global

            % Right Back Foot Value Printing
            app.RBFX.Value=double(Tg24(1,4));
            app.RBFY.Value=double(Tg24(2,4));
            app.RBFZ.Value=double(Tg24(3,4));
            
            % Plot Right Back Leg
            p = plot3(app.NonWaistQuadrupedRobotPlot, [Tg2RBS(1,4), Tg22(1,4), pgRBL1(1,1), Tg23(1,4), Tg24(1,4)], [Tg2RBS(2,4), Tg22(2,4), pgRBL1(2,1), Tg23(2,4), Tg24(2,4)], [Tg2RBS(3,4), Tg22(3,4), pgRBL1(3,1), Tg23(3,4), Tg24(3,4)],'-o','LineWidth',3);
            p.MarkerFaceColor = [1 0.5 0];
            p.MarkerSize = 8;
            p.MarkerEdgeColor = [1 0.5 0];
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Left Back Leg Kinematics & Plot
            % Left Back Foot position according to Body

            % Left Back Foot position according to Global

            % Left Back Foot Value Printing
            app.LBFX.Value=double(Tg24(1,4));
            app.LBFY.Value=double(Tg24(2,4));
            app.LBFZ.Value=double(Tg24(3,4));

            % Plot Left Back Leg
            p = plot3(app.NonWaistQuadrupedRobotPlot, [Tg2LBS(1,4), Tg22(1,4), pgLBL1(1,1), Tg23(1,4), Tg24(1,4)], [Tg2LBS(2,4), Tg22(2,4), pgLBL1(2,1), Tg23(2,4), Tg24(2,4)], [Tg2LBS(3,4), Tg22(3,4), pgLBL1(3,1), Tg23(3,4), Tg24(3,4)],'-o','LineWidth',3);
            p.MarkerFaceColor = [1 0.5 0];
            p.MarkerSize = 8;
            p.MarkerEdgeColor = [1 0.5 0];
            %}
            hold(app.NonWaistQuadrupedRobotPlot,'off');
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.NonWaistQuadrupedRobotPlot.XLim = [-BodyLength/2-BodyWidth/2-BLegSLink-BLegULink1-BLegULink2-BLegLLink BodyLength/2+BodyWidth/2+FLegSLink+FLegULink1+FLegULink2+FLegLLink];
            app.NonWaistQuadrupedRobotPlot.YLim = [-BodyLength/2-BodyWidth/2-BLegSLink-BLegULink1-BLegULink2-BLegLLink BodyLength/2+BodyWidth/2+FLegSLink+FLegULink1+FLegULink2+FLegLLink];
            app.NonWaistQuadrupedRobotPlot.ZLim = [-BodyLength/2-BodyWidth/2-BLegSLink-BLegULink1-BLegULink2-BLegLLink BodyLength/2+BodyWidth/2+FLegSLink+FLegULink1+FLegULink2+FLegLLink];
            app.NonWaistQuadrupedRobotPlot.View = [45 45];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculationStartButton
        function CalculationStartButtonPushed(app, event)
            % Body Link Parameter Receive
            BL = app.BodyLength.Value;
            BW = app.BodyWidth.Value;
            
            % Front Leg Link Parameter Receive
            FSLink = app.FLegSLink.Value;
            FULink1 = app.FLegULink1.Value;
            FULink2 = app.FLegULink2.Value;
            FLLink = app.FLegLLink.Value;
            
            % Back Leg Link Parameter Receive
            BSLink = app.BLegSLink.Value;
            BULink1 = app.BLegULink1.Value;
            BULink2 = app.BLegULink2.Value;
            BLLink = app.BLegLLink.Value;
            
            % Right Front Leg Joint Angle Receive
            RFS=app.RFSJoint.Value;
            RFU=app.RFUJoint.Value;
            RFL=app.RFLJoint.Value;

            % Left Front Leg Joint Angle Receive
            LFS=app.LFSJoint.Value;
            LFU=app.LFUJoint.Value;
            LFL=app.LFLJoint.Value;
            
            % Right Back Leg Joint Angle Receive
            RBS=app.RBSJoint.Value;
            RBU=app.RBUJoint.Value;
            RBL=app.RBLJoint.Value;

            % Left Back Leg Joint Angle Receive
            LBS=app.LBSJoint.Value;
            LBU=app.LBUJoint.Value;
            LBL=app.LBLJoint.Value;

            % Robot Plot
            RobotPlot(app, BL, BW, FSLink, FULink1, FULink2, FLLink, BSLink, BULink1, BULink2, BLLink, RFS, RFU, RFL, LFS, LFU, LFL, RBS, RBU, RBL, LBS, LBU, LBL);
        end

        % Value changed function: BodyLength
        function BodyLengthValueChanged(app, event)
            % Body Link Parameter Receive
            BL = event.Value;
            BW = app.BodyWidth.Value;
            
            % Front Leg Link Parameter Receive
            FSLink = app.FLegSLink.Value;
            FULink1 = app.FLegULink1.Value;
            FULink2 = app.FLegULink2.Value;
            FLLink = app.FLegLLink.Value;
            
            % Back Leg Link Parameter Receive
            BSLink = app.BLegSLink.Value;
            BULink1 = app.BLegULink1.Value;
            BULink2 = app.BLegULink2.Value;
            BLLink = app.BLegLLink.Value;
            
            % Right Front Leg Joint Angle Receive
            RFS=app.RFSJoint.Value;
            RFU=app.RFUJoint.Value;
            RFL=app.RFLJoint.Value;

            % Left Front Leg Joint Angle Receive
            LFS=app.LFSJoint.Value;
            LFU=app.LFUJoint.Value;
            LFL=app.LFLJoint.Value;
            
            % Right Back Leg Joint Angle Receive
            RBS=app.RBSJoint.Value;
            RBU=app.RBUJoint.Value;
            RBL=app.RBLJoint.Value;

            % Left Back Leg Joint Angle Receive
            LBS=app.LBSJoint.Value;
            LBU=app.LBUJoint.Value;
            LBL=app.LBLJoint.Value;

            % Robot Plot
            RobotPlot(app, BL, BW, FSLink, FULink1, FULink2, FLLink, BSLink, BULink1, BULink2, BLLink, RFS, RFU, RFL, LFS, LFU, LFL, RBS, RBU, RBL, LBS, LBU, LBL);
        end

        % Value changed function: BodyWidth
        function BodyWidthValueChanged(app, event)
            % Body Link Parameter Receive
            BL = app.BodyLength.Value;
            BW = event.Value;
            
            % Front Leg Link Parameter Receive
            FSLink = app.FLegSLink.Value;
            FULink1 = app.FLegULink1.Value;
            FULink2 = app.FLegULink2.Value;
            FLLink = app.FLegLLink.Value;
            
            % Back Leg Link Parameter Receive
            BSLink = app.BLegSLink.Value;
            BULink1 = app.BLegULink1.Value;
            BULink2 = app.BLegULink2.Value;
            BLLink = app.BLegLLink.Value;
            
            % Right Front Leg Joint Angle Receive
            RFS=app.RFSJoint.Value;
            RFU=app.RFUJoint.Value;
            RFL=app.RFLJoint.Value;

            % Left Front Leg Joint Angle Receive
            LFS=app.LFSJoint.Value;
            LFU=app.LFUJoint.Value;
            LFL=app.LFLJoint.Value;
            
            % Right Back Leg Joint Angle Receive
            RBS=app.RBSJoint.Value;
            RBU=app.RBUJoint.Value;
            RBL=app.RBLJoint.Value;

            % Left Back Leg Joint Angle Receive
            LBS=app.LBSJoint.Value;
            LBU=app.LBUJoint.Value;
            LBL=app.LBLJoint.Value;

            % Robot Plot
            RobotPlot(app, BL, BW, FSLink, FULink1, FULink2, FLLink, BSLink, BULink1, BULink2, BLLink, RFS, RFU, RFL, LFS, LFU, LFL, RBS, RBU, RBL, LBS, LBU, LBL);
        end

        % Value changed function: FLegSLink
        function FLegSLinkValueChanged(app, event)
            % Body Link Parameter Receive
            BL = app.BodyLength.Value;
            BW = app.BodyWidth.Value;
            
            % Front Leg Link Parameter Receive
            FSLink = event.Value;
            FULink1 = app.FLegULink1.Value;
            FULink2 = app.FLegULink2.Value;
            FLLink = app.FLegLLink.Value;
            
            % Back Leg Link Parameter Receive
            BSLink = app.BLegSLink.Value;
            BULink1 = app.BLegULink1.Value;
            BULink2 = app.BLegULink2.Value;
            BLLink = app.BLegLLink.Value;
            
            % Right Front Leg Joint Angle Receive
            RFS=app.RFSJoint.Value;
            RFU=app.RFUJoint.Value;
            RFL=app.RFLJoint.Value;

            % Left Front Leg Joint Angle Receive
            LFS=app.LFSJoint.Value;
            LFU=app.LFUJoint.Value;
            LFL=app.LFLJoint.Value;
            
            % Right Back Leg Joint Angle Receive
            RBS=app.RBSJoint.Value;
            RBU=app.RBUJoint.Value;
            RBL=app.RBLJoint.Value;

            % Left Back Leg Joint Angle Receive
            LBS=app.LBSJoint.Value;
            LBU=app.LBUJoint.Value;
            LBL=app.LBLJoint.Value;

            % Robot Plot
            RobotPlot(app, BL, BW, FSLink, FULink1, FULink2, FLLink, BSLink, BULink1, BULink2, BLLink, RFS, RFU, RFL, LFS, LFU, LFL, RBS, RBU, RBL, LBS, LBU, LBL);
        end

        % Value changed function: FLegULink1
        function FLegULink1ValueChanged(app, event)
            % Body Link Parameter Receive
            BL = app.BodyLength.Value;
            BW = app.BodyWidth.Value;
            
            % Front Leg Link Parameter Receive
            FSLink = app.FLegSLink.Value;
            FULink1 = event.Value;
            FULink2 = app.FLegULink2.Value;
            FLLink = app.FLegLLink.Value;
            
            % Back Leg Link Parameter Receive
            BSLink = app.BLegSLink.Value;
            BULink1 = app.BLegULink1.Value;
            BULink2 = app.BLegULink2.Value;
            BLLink = app.BLegLLink.Value;
            
            % Right Front Leg Joint Angle Receive
            RFS=app.RFSJoint.Value;
            RFU=app.RFUJoint.Value;
            RFL=app.RFLJoint.Value;

            % Left Front Leg Joint Angle Receive
            LFS=app.LFSJoint.Value;
            LFU=app.LFUJoint.Value;
            LFL=app.LFLJoint.Value;
            
            % Right Back Leg Joint Angle Receive
            RBS=app.RBSJoint.Value;
            RBU=app.RBUJoint.Value;
            RBL=app.RBLJoint.Value;

            % Left Back Leg Joint Angle Receive
            LBS=app.LBSJoint.Value;
            LBU=app.LBUJoint.Value;
            LBL=app.LBLJoint.Value;

            % Robot Plot
            RobotPlot(app, BL, BW, FSLink, FULink1, FULink2, FLLink, BSLink, BULink1, BULink2, BLLink, RFS, RFU, RFL, LFS, LFU, LFL, RBS, RBU, RBL, LBS, LBU, LBL);
        end

        % Value changed function: FLegULink2
        function FLegULink2ValueChanged(app, event)
            % Body Link Parameter Receive
            BL = app.BodyLength.Value;
            BW = app.BodyWidth.Value;
            
            % Front Leg Link Parameter Receive
            FSLink = app.FLegSLink.Value;
            FULink1 = app.FLegULink1.Value;
            FULink2 = event.Value;
            FLLink = app.FLegLLink.Value;
            
            % Back Leg Link Parameter Receive
            BSLink = app.BLegSLink.Value;
            BULink1 = app.BLegULink1.Value;
            BULink2 = app.BLegULink2.Value;
            BLLink = app.BLegLLink.Value;
            
            % Right Front Leg Joint Angle Receive
            RFS=app.RFSJoint.Value;
            RFU=app.RFUJoint.Value;
            RFL=app.RFLJoint.Value;

            % Left Front Leg Joint Angle Receive
            LFS=app.LFSJoint.Value;
            LFU=app.LFUJoint.Value;
            LFL=app.LFLJoint.Value;
            
            % Right Back Leg Joint Angle Receive
            RBS=app.RBSJoint.Value;
            RBU=app.RBUJoint.Value;
            RBL=app.RBLJoint.Value;

            % Left Back Leg Joint Angle Receive
            LBS=app.LBSJoint.Value;
            LBU=app.LBUJoint.Value;
            LBL=app.LBLJoint.Value;

            % Robot Plot
            RobotPlot(app, BL, BW, FSLink, FULink1, FULink2, FLLink, BSLink, BULink1, BULink2, BLLink, RFS, RFU, RFL, LFS, LFU, LFL, RBS, RBU, RBL, LBS, LBU, LBL);
        end

        % Value changed function: FLegLLink
        function FLegLLinkValueChanged(app, event)
            % Body Link Parameter Receive
            BL = app.BodyLength.Value;
            BW = app.BodyWidth.Value;
            
            % Front Leg Link Parameter Receive
            FSLink = app.FLegSLink.Value;
            FULink1 = app.FLegULink1.Value;
            FULink2 = app.FLegULink2.Value;
            FLLink = event.Value;
            
            % Back Leg Link Parameter Receive
            BSLink = app.BLegSLink.Value;
            BULink1 = app.BLegULink1.Value;
            BULink2 = app.BLegULink2.Value;
            BLLink = app.BLegLLink.Value;
            
            % Right Front Leg Joint Angle Receive
            RFS=app.RFSJoint.Value;
            RFU=app.RFUJoint.Value;
            RFL=app.RFLJoint.Value;

            % Left Front Leg Joint Angle Receive
            LFS=app.LFSJoint.Value;
            LFU=app.LFUJoint.Value;
            LFL=app.LFLJoint.Value;
            
            % Right Back Leg Joint Angle Receive
            RBS=app.RBSJoint.Value;
            RBU=app.RBUJoint.Value;
            RBL=app.RBLJoint.Value;

            % Left Back Leg Joint Angle Receive
            LBS=app.LBSJoint.Value;
            LBU=app.LBUJoint.Value;
            LBL=app.LBLJoint.Value;

            % Robot Plot
            RobotPlot(app, BL, BW, FSLink, FULink1, FULink2, FLLink, BSLink, BULink1, BULink2, BLLink, RFS, RFU, RFL, LFS, LFU, LFL, RBS, RBU, RBL, LBS, LBU, LBL);
        end

        % Value changed function: BLegSLink
        function BLegSLinkValueChanged(app, event)
            % Body Link Parameter Receive
            BL = app.BodyLength.Value;
            BW = app.BodyWidth.Value;
            
            % Front Leg Link Parameter Receive
            FSLink = app.FLegSLink.Value;
            FULink1 = app.FLegULink1.Value;
            FULink2 = app.FLegULink2.Value;
            FLLink = app.FLegLLink.Value;
            
            % Back Leg Link Parameter Receive
            BSLink = event.Value;
            BULink1 = app.BLegULink1.Value;
            BULink2 = app.BLegULink2.Value;
            BLLink = app.BLegLLink.Value;
            
            % Right Front Leg Joint Angle Receive
            RFS=app.RFSJoint.Value;
            RFU=app.RFUJoint.Value;
            RFL=app.RFLJoint.Value;

            % Left Front Leg Joint Angle Receive
            LFS=app.LFSJoint.Value;
            LFU=app.LFUJoint.Value;
            LFL=app.LFLJoint.Value;
            
            % Right Back Leg Joint Angle Receive
            RBS=app.RBSJoint.Value;
            RBU=app.RBUJoint.Value;
            RBL=app.RBLJoint.Value;

            % Left Back Leg Joint Angle Receive
            LBS=app.LBSJoint.Value;
            LBU=app.LBUJoint.Value;
            LBL=app.LBLJoint.Value;

            % Robot Plot
            RobotPlot(app, BL, BW, FSLink, FULink1, FULink2, FLLink, BSLink, BULink1, BULink2, BLLink, RFS, RFU, RFL, LFS, LFU, LFL, RBS, RBU, RBL, LBS, LBU, LBL);
        end

        % Value changed function: BLegULink1
        function BLegULink1ValueChanged(app, event)
            % Body Link Parameter Receive
            BL = app.BodyLength.Value;
            BW = app.BodyWidth.Value;
            
            % Front Leg Link Parameter Receive
            FSLink = app.FLegSLink.Value;
            FULink1 = app.FLegULink1.Value;
            FULink2 = app.FLegULink2.Value;
            FLLink = app.FLegLLink.Value;
            
            % Back Leg Link Parameter Receive
            BSLink = app.BLegSLink.Value;
            BULink1 = event.Value;
            BULink2 = app.BLegULink2.Value;
            BLLink = app.BLegLLink.Value;
            
            % Right Front Leg Joint Angle Receive
            RFS=app.RFSJoint.Value;
            RFU=app.RFUJoint.Value;
            RFL=app.RFLJoint.Value;

            % Left Front Leg Joint Angle Receive
            LFS=app.LFSJoint.Value;
            LFU=app.LFUJoint.Value;
            LFL=app.LFLJoint.Value;
            
            % Right Back Leg Joint Angle Receive
            RBS=app.RBSJoint.Value;
            RBU=app.RBUJoint.Value;
            RBL=app.RBLJoint.Value;

            % Left Back Leg Joint Angle Receive
            LBS=app.LBSJoint.Value;
            LBU=app.LBUJoint.Value;
            LBL=app.LBLJoint.Value;

            % Robot Plot
            RobotPlot(app, BL, BW, FSLink, FULink1, FULink2, FLLink, BSLink, BULink1, BULink2, BLLink, RFS, RFU, RFL, LFS, LFU, LFL, RBS, RBU, RBL, LBS, LBU, LBL);
        end

        % Value changed function: BLegULink2
        function BLegULink2ValueChanged(app, event)
            % Body Link Parameter Receive
            BL = app.BodyLength.Value;
            BW = app.BodyWidth.Value;
            
            % Front Leg Link Parameter Receive
            FSLink = app.FLegSLink.Value;
            FULink1 = app.FLegULink1.Value;
            FULink2 = event.Value;
            FLLink = app.FLegLLink.Value;
            
            % Back Leg Link Parameter Receive
            BSLink = app.BLegSLink.Value;
            BULink1 = app.BLegULink1.Value;
            BULink2 = app.BLegULink2.Value;
            BLLink = app.BLegLLink.Value;
            
            % Right Front Leg Joint Angle Receive
            RFS=app.RFSJoint.Value;
            RFU=app.RFUJoint.Value;
            RFL=app.RFLJoint.Value;

            % Left Front Leg Joint Angle Receive
            LFS=app.LFSJoint.Value;
            LFU=app.LFUJoint.Value;
            LFL=app.LFLJoint.Value;
            
            % Right Back Leg Joint Angle Receive
            RBS=app.RBSJoint.Value;
            RBU=app.RBUJoint.Value;
            RBL=app.RBLJoint.Value;

            % Left Back Leg Joint Angle Receive
            LBS=app.LBSJoint.Value;
            LBU=app.LBUJoint.Value;
            LBL=app.LBLJoint.Value;

            % Robot Plot
            RobotPlot(app, BL, BW, FSLink, FULink1, FULink2, FLLink, BSLink, BULink1, BULink2, BLLink, RFS, RFU, RFL, LFS, LFU, LFL, RBS, RBU, RBL, LBS, LBU, LBL);
        end

        % Value changed function: BLegLLink
        function BLegLLinkValueChanged(app, event)
            % Body Link Parameter Receive
            BL = app.BodyLength.Value;
            BW = app.BodyWidth.Value;
            
            % Front Leg Link Parameter Receive
            FSLink = app.FLegSLink.Value;
            FULink1 = app.FLegULink1.Value;
            FULink2 = app.FLegULink2.Value;
            FLLink = app.FLegLLink.Value;
            
            % Back Leg Link Parameter Receive
            BSLink = app.BLegSLink.Value;
            BULink1 = app.BLegULink1.Value;
            BULink2 = app.BLegULink2.Value;
            BLLink = event.Value;
            
            % Right Front Leg Joint Angle Receive
            RFS=app.RFSJoint.Value;
            RFU=app.RFUJoint.Value;
            RFL=app.RFLJoint.Value;

            % Left Front Leg Joint Angle Receive
            LFS=app.LFSJoint.Value;
            LFU=app.LFUJoint.Value;
            LFL=app.LFLJoint.Value;
            
            % Right Back Leg Joint Angle Receive
            RBS=app.RBSJoint.Value;
            RBU=app.RBUJoint.Value;
            RBL=app.RBLJoint.Value;

            % Left Back Leg Joint Angle Receive
            LBS=app.LBSJoint.Value;
            LBU=app.LBUJoint.Value;
            LBL=app.LBLJoint.Value;

            % Robot Plot
            RobotPlot(app, BL, BW, FSLink, FULink1, FULink2, FLLink, BSLink, BULink1, BULink2, BLLink, RFS, RFU, RFL, LFS, LFU, LFL, RBS, RBU, RBL, LBS, LBU, LBL);
        end

        % Callback function: RFSJoint, RFSJoint
        function RFSJointValueChanging(app, event)
            % Body Link Parameter Receive
            BL = app.BodyLength.Value;
            BW = app.BodyWidth.Value;
            
            % Front Leg Link Parameter Receive
            FSLink = app.FLegSLink.Value;
            FULink1 = app.FLegULink1.Value;
            FULink2 = app.FLegULink2.Value;
            FLLink = app.FLegLLink.Value;
            
            % Back Leg Link Parameter Receive
            BSLink = app.BLegSLink.Value;
            BULink1 = app.BLegULink1.Value;
            BULink2 = app.BLegULink2.Value;
            BLLink = app.BLegLLink.Value;
            
            % Right Front Leg Joint Angle Receive
            RFS=event.Value;
            RFU=app.RFUJoint.Value;
            RFL=app.RFLJoint.Value;

            % Left Front Leg Joint Angle Receive
            LFS=app.LFSJoint.Value;
            LFU=app.LFUJoint.Value;
            LFL=app.LFLJoint.Value;
            
            % Right Back Leg Joint Angle Receive
            RBS=app.RBSJoint.Value;
            RBU=app.RBUJoint.Value;
            RBL=app.RBLJoint.Value;

            % Left Back Leg Joint Angle Receive
            LBS=app.LBSJoint.Value;
            LBU=app.LBUJoint.Value;
            LBL=app.LBLJoint.Value;

            % Robot Plot
            RobotPlot(app, BL, BW, FSLink, FULink1, FULink2, FLLink, BSLink, BULink1, BULink2, BLLink, RFS, RFU, RFL, LFS, LFU, LFL, RBS, RBU, RBL, LBS, LBU, LBL);
        end

        % Callback function: RFUJoint, RFUJoint
        function RFUJointValueChanging(app, event)
            % Body Link Parameter Receive
            BL = app.BodyLength.Value;
            BW = app.BodyWidth.Value;
            
            % Front Leg Link Parameter Receive
            FSLink = app.FLegSLink.Value;
            FULink1 = app.FLegULink1.Value;
            FULink2 = app.FLegULink2.Value;
            FLLink = app.FLegLLink.Value;
            
            % Back Leg Link Parameter Receive
            BSLink = app.BLegSLink.Value;
            BULink1 = app.BLegULink1.Value;
            BULink2 = app.BLegULink2.Value;
            BLLink = app.BLegLLink.Value;
            
            % Right Front Leg Joint Angle Receive
            RFS=app.RFSJoint.Value;
            RFU=event.Value;
            RFL=app.RFLJoint.Value;

            % Left Front Leg Joint Angle Receive
            LFS=app.LFSJoint.Value;
            LFU=app.LFUJoint.Value;
            LFL=app.LFLJoint.Value;
            
            % Right Back Leg Joint Angle Receive
            RBS=app.RBSJoint.Value;
            RBU=app.RBUJoint.Value;
            RBL=app.RBLJoint.Value;

            % Left Back Leg Joint Angle Receive
            LBS=app.LBSJoint.Value;
            LBU=app.LBUJoint.Value;
            LBL=app.LBLJoint.Value;

            % Robot Plot
            RobotPlot(app, BL, BW, FSLink, FULink1, FULink2, FLLink, BSLink, BULink1, BULink2, BLLink, RFS, RFU, RFL, LFS, LFU, LFL, RBS, RBU, RBL, LBS, LBU, LBL);
        end

        % Callback function: RFLJoint, RFLJoint
        function RFLJointValueChanging(app, event)
            % Body Link Parameter Receive
            BL = app.BodyLength.Value;
            BW = app.BodyWidth.Value;
            
            % Front Leg Link Parameter Receive
            FSLink = app.FLegSLink.Value;
            FULink1 = app.FLegULink1.Value;
            FULink2 = app.FLegULink2.Value;
            FLLink = app.FLegLLink.Value;
            
            % Back Leg Link Parameter Receive
            BSLink = app.BLegSLink.Value;
            BULink1 = app.BLegULink1.Value;
            BULink2 = app.BLegULink2.Value;
            BLLink = app.BLegLLink.Value;
            
            % Right Front Leg Joint Angle Receive
            RFS=app.RFSJoint.Value;
            RFU=app.RFUJoint.Value;
            RFL=event.Value;

            % Left Front Leg Joint Angle Receive
            LFS=app.LFSJoint.Value;
            LFU=app.LFUJoint.Value;
            LFL=app.LFLJoint.Value;
            
            % Right Back Leg Joint Angle Receive
            RBS=app.RBSJoint.Value;
            RBU=app.RBUJoint.Value;
            RBL=app.RBLJoint.Value;

            % Left Back Leg Joint Angle Receive
            LBS=app.LBSJoint.Value;
            LBU=app.LBUJoint.Value;
            LBL=app.LBLJoint.Value;

            % Robot Plot
            RobotPlot(app, BL, BW, FSLink, FULink1, FULink2, FLLink, BSLink, BULink1, BULink2, BLLink, RFS, RFU, RFL, LFS, LFU, LFL, RBS, RBU, RBL, LBS, LBU, LBL);
        end

        % Callback function: LFSJoint, LFSJoint
        function LFSJointValueChanging(app, event)
            % Body Link Parameter Receive
            BL = app.BodyLength.Value;
            BW = app.BodyWidth.Value;
            
            % Front Leg Link Parameter Receive
            FSLink = app.FLegSLink.Value;
            FULink1 = app.FLegULink1.Value;
            FULink2 = app.FLegULink2.Value;
            FLLink = app.FLegLLink.Value;
            
            % Back Leg Link Parameter Receive
            BSLink = app.BLegSLink.Value;
            BULink1 = app.BLegULink1.Value;
            BULink2 = app.BLegULink2.Value;
            BLLink = app.BLegLLink.Value;
            
            % Right Front Leg Joint Angle Receive
            RFS=app.RFSJoint.Value;
            RFU=app.RFUJoint.Value;
            RFL=app.RFLJoint.Value;

            % Left Front Leg Joint Angle Receive
            LFS=event.Value;
            LFU=app.LFUJoint.Value;
            LFL=app.LFLJoint.Value;
            
            % Right Back Leg Joint Angle Receive
            RBS=app.RBSJoint.Value;
            RBU=app.RBUJoint.Value;
            RBL=app.RBLJoint.Value;

            % Left Back Leg Joint Angle Receive
            LBS=app.LBSJoint.Value;
            LBU=app.LBUJoint.Value;
            LBL=app.LBLJoint.Value;

            % Robot Plot
            RobotPlot(app, BL, BW, FSLink, FULink1, FULink2, FLLink, BSLink, BULink1, BULink2, BLLink, RFS, RFU, RFL, LFS, LFU, LFL, RBS, RBU, RBL, LBS, LBU, LBL);
        end

        % Callback function: LFUJoint, LFUJoint
        function LFUJointValueChanging(app, event)
            % Body Link Parameter Receive
            BL = app.BodyLength.Value;
            BW = app.BodyWidth.Value;
            
            % Front Leg Link Parameter Receive
            FSLink = app.FLegSLink.Value;
            FULink1 = app.FLegULink1.Value;
            FULink2 = app.FLegULink2.Value;
            FLLink = app.FLegLLink.Value;
            
            % Back Leg Link Parameter Receive
            BSLink = app.BLegSLink.Value;
            BULink1 = app.BLegULink1.Value;
            BULink2 = app.BLegULink2.Value;
            BLLink = app.BLegLLink.Value;
            
            % Right Front Leg Joint Angle Receive
            RFS=app.RFSJoint.Value;
            RFU=app.RFUJoint.Value;
            RFL=app.RFLJoint.Value;

            % Left Front Leg Joint Angle Receive
            LFS=app.LFSJoint.Value;
            LFU=event.Value;
            LFL=app.LFLJoint.Value;
            
            % Right Back Leg Joint Angle Receive
            RBS=app.RBSJoint.Value;
            RBU=app.RBUJoint.Value;
            RBL=app.RBLJoint.Value;

            % Left Back Leg Joint Angle Receive
            LBS=app.LBSJoint.Value;
            LBU=app.LBUJoint.Value;
            LBL=app.LBLJoint.Value;

            % Robot Plot
            RobotPlot(app, BL, BW, FSLink, FULink1, FULink2, FLLink, BSLink, BULink1, BULink2, BLLink, RFS, RFU, RFL, LFS, LFU, LFL, RBS, RBU, RBL, LBS, LBU, LBL);
        end

        % Callback function: LFLJoint, LFLJoint
        function LFLJointValueChanging(app, event)
            % Body Link Parameter Receive
            BL = app.BodyLength.Value;
            BW = app.BodyWidth.Value;
            
            % Front Leg Link Parameter Receive
            FSLink = app.FLegSLink.Value;
            FULink1 = app.FLegULink1.Value;
            FULink2 = app.FLegULink2.Value;
            FLLink = app.FLegLLink.Value;
            
            % Back Leg Link Parameter Receive
            BSLink = app.BLegSLink.Value;
            BULink1 = app.BLegULink1.Value;
            BULink2 = app.BLegULink2.Value;
            BLLink = app.BLegLLink.Value;
            
            % Right Front Leg Joint Angle Receive
            RFS=app.RFSJoint.Value;
            RFU=app.RFUJoint.Value;
            RFL=app.RFLJoint.Value;

            % Left Front Leg Joint Angle Receive
            LFS=app.LFSJoint.Value;
            LFU=app.LFUJoint.Value;
            LFL=event.Value;
            
            % Right Back Leg Joint Angle Receive
            RBS=app.RBSJoint.Value;
            RBU=app.RBUJoint.Value;
            RBL=app.RBLJoint.Value;

            % Left Back Leg Joint Angle Receive
            LBS=app.LBSJoint.Value;
            LBU=app.LBUJoint.Value;
            LBL=app.LBLJoint.Value;

            % Robot Plot
            RobotPlot(app, BL, BW, FSLink, FULink1, FULink2, FLLink, BSLink, BULink1, BULink2, BLLink, RFS, RFU, RFL, LFS, LFU, LFL, RBS, RBU, RBL, LBS, LBU, LBL);
        end

        % Value changing function: RBSJoint
        function RBSJointValueChanging(app, event)
            % Body Link Parameter Receive
            BL = app.BodyLength.Value;
            BW = app.BodyWidth.Value;
            
            % Front Leg Link Parameter Receive
            FSLink = app.FLegSLink.Value;
            FULink1 = app.FLegULink1.Value;
            FULink2 = app.FLegULink2.Value;
            FLLink = app.FLegLLink.Value;
            
            % Back Leg Link Parameter Receive
            BSLink = app.BLegSLink.Value;
            BULink1 = app.BLegULink1.Value;
            BULink2 = app.BLegULink2.Value;
            BLLink = app.BLegLLink.Value;
            
            % Right Front Leg Joint Angle Receive
            RFS=app.RFSJoint.Value;
            RFU=app.RFUJoint.Value;
            RFL=app.RFLJoint.Value;

            % Left Front Leg Joint Angle Receive
            LFS=app.LFSJoint.Value;
            LFU=app.LFUJoint.Value;
            LFL=app.LFLJoint.Value;
            
            % Right Back Leg Joint Angle Receive
            RBS=event.Value;
            RBU=app.RBUJoint.Value;
            RBL=app.RBLJoint.Value;

            % Left Back Leg Joint Angle Receive
            LBS=app.LBSJoint.Value;
            LBU=app.LBUJoint.Value;
            LBL=app.LBLJoint.Value;

            % Robot Plot
            RobotPlot(app, BL, BW, FSLink, FULink1, FULink2, FLLink, BSLink, BULink1, BULink2, BLLink, RFS, RFU, RFL, LFS, LFU, LFL, RBS, RBU, RBL, LBS, LBU, LBL);
        end

        % Callback function: RBSJoint, RBUJoint, RBUJoint
        function RBUJointValueChanging(app, event)
            % Body Link Parameter Receive
            BL = app.BodyLength.Value;
            BW = app.BodyWidth.Value;
            
            % Front Leg Link Parameter Receive
            FSLink = app.FLegSLink.Value;
            FULink1 = app.FLegULink1.Value;
            FULink2 = app.FLegULink2.Value;
            FLLink = app.FLegLLink.Value;
            
            % Back Leg Link Parameter Receive
            BSLink = app.BLegSLink.Value;
            BULink1 = app.BLegULink1.Value;
            BULink2 = app.BLegULink2.Value;
            BLLink = app.BLegLLink.Value;
            
            % Right Front Leg Joint Angle Receive
            RFS=app.RFSJoint.Value;
            RFU=app.RFUJoint.Value;
            RFL=app.RFLJoint.Value;

            % Left Front Leg Joint Angle Receive
            LFS=app.LFSJoint.Value;
            LFU=app.LFUJoint.Value;
            LFL=app.LFLJoint.Value;
            
            % Right Back Leg Joint Angle Receive
            RBS=app.RBSJoint.Value;
            RBU=event.Value;
            RBL=app.RBLJoint.Value;

            % Left Back Leg Joint Angle Receive
            LBS=app.LBSJoint.Value;
            LBU=app.LBUJoint.Value;
            LBL=app.LBLJoint.Value;

            % Robot Plot
            RobotPlot(app, BL, BW, FSLink, FULink1, FULink2, FLLink, BSLink, BULink1, BULink2, BLLink, RFS, RFU, RFL, LFS, LFU, LFL, RBS, RBU, RBL, LBS, LBU, LBL);
        end

        % Callback function: RBLJoint, RBLJoint
        function RBLJointValueChanging(app, event)
            % Body Link Parameter Receive
            BL = app.BodyLength.Value;
            BW = app.BodyWidth.Value;
            
            % Front Leg Link Parameter Receive
            FSLink = app.FLegSLink.Value;
            FULink1 = app.FLegULink1.Value;
            FULink2 = app.FLegULink2.Value;
            FLLink = app.FLegLLink.Value;
            
            % Back Leg Link Parameter Receive
            BSLink = app.BLegSLink.Value;
            BULink1 = app.BLegULink1.Value;
            BULink2 = app.BLegULink2.Value;
            BLLink = app.BLegLLink.Value;
            
            % Right Front Leg Joint Angle Receive
            RFS=app.RFSJoint.Value;
            RFU=app.RFUJoint.Value;
            RFL=app.RFLJoint.Value;

            % Left Front Leg Joint Angle Receive
            LFS=app.LFSJoint.Value;
            LFU=app.LFUJoint.Value;
            LFL=app.LFLJoint.Value;
            
            % Right Back Leg Joint Angle Receive
            RBS=app.RBSJoint.Value;
            RBU=app.RBUJoint.Value;
            RBL=event.Value;

            % Left Back Leg Joint Angle Receive
            LBS=app.LBSJoint.Value;
            LBU=app.LBUJoint.Value;
            LBL=app.LBLJoint.Value;

            % Robot Plot
            RobotPlot(app, BL, BW, FSLink, FULink1, FULink2, FLLink, BSLink, BULink1, BULink2, BLLink, RFS, RFU, RFL, LFS, LFU, LFL, RBS, RBU, RBL, LBS, LBU, LBL);
        end

        % Callback function: LBSJoint, LBSJoint
        function LBSJointValueChanging(app, event)
            % Body Link Parameter Receive
            BL = app.BodyLength.Value;
            BW = app.BodyWidth.Value;
            
            % Front Leg Link Parameter Receive
            FSLink = app.FLegSLink.Value;
            FULink1 = app.FLegULink1.Value;
            FULink2 = app.FLegULink2.Value;
            FLLink = app.FLegLLink.Value;
            
            % Back Leg Link Parameter Receive
            BSLink = app.BLegSLink.Value;
            BULink1 = app.BLegULink1.Value;
            BULink2 = app.BLegULink2.Value;
            BLLink = app.BLegLLink.Value;
            
            % Right Front Leg Joint Angle Receive
            RFS=app.RFSJoint.Value;
            RFU=app.RFUJoint.Value;
            RFL=app.RFLJoint.Value;

            % Left Front Leg Joint Angle Receive
            LFS=app.LFSJoint.Value;
            LFU=app.LFUJoint.Value;
            LFL=app.LFLJoint.Value;
            
            % Right Back Leg Joint Angle Receive
            RBS=app.RBSJoint.Value;
            RBU=app.RBUJoint.Value;
            RBL=app.RBLJoint.Value;

            % Left Back Leg Joint Angle Receive
            LBS=event.Value;
            LBU=app.LBUJoint.Value;
            LBL=app.LBLJoint.Value;

            % Robot Plot
            RobotPlot(app, BL, BW, FSLink, FULink1, FULink2, FLLink, BSLink, BULink1, BULink2, BLLink, RFS, RFU, RFL, LFS, LFU, LFL, RBS, RBU, RBL, LBS, LBU, LBL);
        end

        % Callback function: LBUJoint, LBUJoint
        function LBUJointValueChanging(app, event)
            % Body Link Parameter Receive
            BL = app.BodyLength.Value;
            BW = app.BodyWidth.Value;
            
            % Front Leg Link Parameter Receive
            FSLink = app.FLegSLink.Value;
            FULink1 = app.FLegULink1.Value;
            FULink2 = app.FLegULink2.Value;
            FLLink = app.FLegLLink.Value;
            
            % Back Leg Link Parameter Receive
            BSLink = app.BLegSLink.Value;
            BULink1 = app.BLegULink1.Value;
            BULink2 = app.BLegULink2.Value;
            BLLink = app.BLegLLink.Value;
            
            % Right Front Leg Joint Angle Receive
            RFS=app.RFSJoint.Value;
            RFU=app.RFUJoint.Value;
            RFL=app.RFLJoint.Value;

            % Left Front Leg Joint Angle Receive
            LFS=app.LFSJoint.Value;
            LFU=app.LFUJoint.Value;
            LFL=app.LFLJoint.Value;
            
            % Right Back Leg Joint Angle Receive
            RBS=app.RBSJoint.Value;
            RBU=app.RBUJoint.Value;
            RBL=app.RBLJoint.Value;

            % Left Back Leg Joint Angle Receive
            LBS=app.LBSJoint.Value;
            LBU=event.Value;
            LBL=app.LBLJoint.Value;

            % Robot Plot
            RobotPlot(app, BL, BW, FSLink, FULink1, FULink2, FLLink, BSLink, BULink1, BULink2, BLLink, RFS, RFU, RFL, LFS, LFU, LFL, RBS, RBU, RBL, LBS, LBU, LBL);
        end

        % Callback function: LBLJoint, LBLJoint
        function LBLJointValueChanging(app, event)
            % Body Link Parameter Receive
            BL = app.BodyLength.Value;
            BW = app.BodyWidth.Value;
            
            % Front Leg Link Parameter Receive
            FSLink = app.FLegSLink.Value;
            FULink1 = app.FLegULink1.Value;
            FULink2 = app.FLegULink2.Value;
            FLLink = app.FLegLLink.Value;
            
            % Back Leg Link Parameter Receive
            BSLink = app.BLegSLink.Value;
            BULink1 = app.BLegULink1.Value;
            BULink2 = app.BLegULink2.Value;
            BLLink = app.BLegLLink.Value;
            
            % Right Front Leg Joint Angle Receive
            RFS=app.RFSJoint.Value;
            RFU=app.RFUJoint.Value;
            RFL=app.RFLJoint.Value;

            % Left Front Leg Joint Angle Receive
            LFS=app.LFSJoint.Value;
            LFU=app.LFUJoint.Value;
            LFL=app.LFLJoint.Value;
            
            % Right Back Leg Joint Angle Receive
            RBS=app.RBSJoint.Value;
            RBU=app.RBUJoint.Value;
            RBL=app.RBLJoint.Value;

            % Left Back Leg Joint Angle Receive
            LBS=app.LBSJoint.Value;
            LBU=app.LBUJoint.Value;
            LBL=event.Value;

            % Robot Plot
            RobotPlot(app, BL, BW, FSLink, FULink1, FULink2, FLLink, BSLink, BULink1, BULink2, BLLink, RFS, RFU, RFL, LFS, LFU, LFL, RBS, RBU, RBL, LBS, LBU, LBL);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NonWaistQuadrupedRobotPlot = uiaxes(app.UIFigure);
            title(app.NonWaistQuadrupedRobotPlot, 'Non-Waist Quadruped Robot Forward Kinematics')
            xlabel(app.NonWaistQuadrupedRobotPlot, 'X')
            ylabel(app.NonWaistQuadrupedRobotPlot, 'Y')
            zlabel(app.NonWaistQuadrupedRobotPlot, 'Z')
            app.CalculationStartButton = uibutton(app.UIFigure, 'push');
            app.CalculationStartButton.ButtonPushedFcn = createCallbackFcn(app, @CalculationStartButtonPushed, true);
            app.CalculationStartButton.Text = 'Calculation Start';
            app.PositionofCenterofBodyPanel = uipanel(app.UIFigure);
            app.PositionofCenterofBodyPanel.Title = 'Position of Center of Body';
            app.XEditFieldLabel = uilabel(app.PositionofCenterofBodyPanel);
            app.XEditFieldLabel.Text = 'X';
            app.CoBX = uieditfield(app.PositionofCenterofBodyPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.PositionofCenterofBodyPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.CoBZ = uieditfield(app.PositionofCenterofBodyPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.PositionofCenterofBodyPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.CoBY = uieditfield(app.PositionofCenterofBodyPanel, 'numeric');
            app.RightFrontLegActuatorControlPanel = uipanel(app.UIFigure);
            app.RightFrontLegActuatorControlPanel.Title = 'Right Front Leg Actuator Control';
            app.ShoulderSliderLabel = uilabel(app.RightFrontLegActuatorControlPanel);
            app.ShoulderSliderLabel.Text = 'Shoulder';
            app.RFSJoint = uislider(app.RightFrontLegActuatorControlPanel);
            app.RFSJoint.Limits = [-3.14159265358979 3.14159265358979];
            app.RFSJoint.ValueChangedFcn = createCallbackFcn(app, @RFSJointValueChanging, true);
            app.RFSJoint.ValueChangingFcn = createCallbackFcn(app, @RFSJointValueChanging, true);
            app.UpperSliderLabel = uilabel(app.RightFrontLegActuatorControlPanel);
            app.UpperSliderLabel.Text = 'Upper';
            app.RFUJoint = uislider(app.RightFrontLegActuatorControlPanel);
            app.RFUJoint.Limits = [-3.14159265358979 3.14159265358979];
            app.RFUJoint.ValueChangedFcn = createCallbackFcn(app, @RFUJointValueChanging, true);
            app.RFUJoint.ValueChangingFcn = createCallbackFcn(app, @RFUJointValueChanging, true);
            app.RFUJoint.Value = -0.523598775598299;
            app.LowerSliderLabel = uilabel(app.RightFrontLegActuatorControlPanel);
            app.LowerSliderLabel.Text = 'Lower';
            app.RFLJoint = uislider(app.RightFrontLegActuatorControlPanel);
            app.RFLJoint.Limits = [-3.14159265358979 3.14159265358979];
            app.RFLJoint.ValueChangedFcn = createCallbackFcn(app, @RFLJointValueChanging, true);
            app.RFLJoint.ValueChangingFcn = createCallbackFcn(app, @RFLJointValueChanging, true);
            app.RFLJoint.Value = 1.0471975511966;
            app.LeftFrontLegActuatorControlPanel = uipanel(app.UIFigure);
            app.LeftFrontLegActuatorControlPanel.Title = 'Left Front Leg Actuator Control';
            app.ShoulderSlider_2Label = uilabel(app.LeftFrontLegActuatorControlPanel);
            app.ShoulderSlider_2Label.Text = 'Shoulder';
            app.LFSJoint = uislider(app.LeftFrontLegActuatorControlPanel);
            app.LFSJoint.Limits = [-3.14159265358979 3.14159265358979];
            app.LFSJoint.ValueChangedFcn = createCallbackFcn(app, @LFSJointValueChanging, true);
            app.LFSJoint.ValueChangingFcn = createCallbackFcn(app, @LFSJointValueChanging, true);
            app.UpperSlider_2Label = uilabel(app.LeftFrontLegActuatorControlPanel);
            app.UpperSlider_2Label.Text = 'Upper';
            app.LFUJoint = uislider(app.LeftFrontLegActuatorControlPanel);
            app.LFUJoint.Limits = [-3.14159265358979 3.14159265358979];
            app.LFUJoint.ValueChangedFcn = createCallbackFcn(app, @LFUJointValueChanging, true);
            app.LFUJoint.ValueChangingFcn = createCallbackFcn(app, @LFUJointValueChanging, true);
            app.LFUJoint.Value = -0.523598775598299;
            app.LowerSlider_2Label = uilabel(app.LeftFrontLegActuatorControlPanel);
            app.LowerSlider_2Label.Text = 'Lower';
            app.LFLJoint = uislider(app.LeftFrontLegActuatorControlPanel);
            app.LFLJoint.Limits = [-3.14159265358979 3.14159265358979];
            app.LFLJoint.ValueChangedFcn = createCallbackFcn(app, @LFLJointValueChanging, true);
            app.LFLJoint.ValueChangingFcn = createCallbackFcn(app, @LFLJointValueChanging, true);
            app.LFLJoint.Value = 1.0471975511966;
            app.RightBackLegActuatorControlPanel = uipanel(app.UIFigure);
            app.RightBackLegActuatorControlPanel.Title = 'Right Back Leg Actuator Control';
            app.ShoulderSlider_3Label = uilabel(app.RightBackLegActuatorControlPanel);
            app.ShoulderSlider_3Label.Text = 'Shoulder';
            app.RBSJoint = uislider(app.RightBackLegActuatorControlPanel);
            app.RBSJoint.Limits = [-3.14159265358979 3.14159265358979];
            app.RBSJoint.ValueChangedFcn = createCallbackFcn(app, @RBUJointValueChanging, true);
            app.RBSJoint.ValueChangingFcn = createCallbackFcn(app, @RBSJointValueChanging, true);
            app.UpperSlider_3Label = uilabel(app.RightBackLegActuatorControlPanel);
            app.UpperSlider_3Label.Text = 'Upper';
            app.RBUJoint = uislider(app.RightBackLegActuatorControlPanel);
            app.RBUJoint.Limits = [-3.14159265358979 3.14159265358979];
            app.RBUJoint.ValueChangedFcn = createCallbackFcn(app, @RBUJointValueChanging, true);
            app.RBUJoint.ValueChangingFcn = createCallbackFcn(app, @RBUJointValueChanging, true);
            app.RBUJoint.Value = -0.523598775598299;
            app.LowerSlider_3Label = uilabel(app.RightBackLegActuatorControlPanel);
            app.LowerSlider_3Label.Text = 'Lower';
            app.RBLJoint = uislider(app.RightBackLegActuatorControlPanel);
            app.RBLJoint.Limits = [-3.14159265358979 3.14159265358979];
            app.RBLJoint.ValueChangedFcn = createCallbackFcn(app, @RBLJointValueChanging, true);
            app.RBLJoint.ValueChangingFcn = createCallbackFcn(app, @RBLJointValueChanging, true);
            app.RBLJoint.Value = 1.0471975511966;
            app.LeftBackLegActuatorControlPanel = uipanel(app.UIFigure);
            app.LeftBackLegActuatorControlPanel.Title = 'Left Back Leg Actuator Control';
            app.ShoulderSlider_4Label = uilabel(app.LeftBackLegActuatorControlPanel);
            app.ShoulderSlider_4Label.Text = 'Shoulder';
            app.LBSJoint = uislider(app.LeftBackLegActuatorControlPanel);
            app.LBSJoint.Limits = [-3.14159265358979 3.14159265358979];
            app.LBSJoint.ValueChangedFcn = createCallbackFcn(app, @LBSJointValueChanging, true);
            app.LBSJoint.ValueChangingFcn = createCallbackFcn(app, @LBSJointValueChanging, true);
            app.UpperSlider_4Label = uilabel(app.LeftBackLegActuatorControlPanel);
            app.UpperSlider_4Label.Text = 'Upper';
            app.LBUJoint = uislider(app.LeftBackLegActuatorControlPanel);
            app.LBUJoint.Limits = [-3.14159265358979 3.14159265358979];
            app.LBUJoint.ValueChangedFcn = createCallbackFcn(app, @LBUJointValueChanging, true);
            app.LBUJoint.ValueChangingFcn = createCallbackFcn(app, @LBUJointValueChanging, true);
            app.LBUJoint.Value = -0.523598775598299;
            app.LowerSlider_4Label = uilabel(app.LeftBackLegActuatorControlPanel);
            app.LowerSlider_4Label.Text = 'Lower';
            app.LBLJoint = uislider(app.LeftBackLegActuatorControlPanel);
            app.LBLJoint.Limits = [-3.14159265358979 3.14159265358979];
            app.LBLJoint.ValueChangedFcn = createCallbackFcn(app, @LBLJointValueChanging, true);
            app.LBLJoint.ValueChangingFcn = createCallbackFcn(app, @LBLJointValueChanging, true);
            app.LBLJoint.Value = 1.0471975511966;
            app.RFFootPanel = uipanel(app.UIFigure);
            app.RFFootPanel.Title = 'Position of Right Front Foot';
            app.XEditField_2Label = uilabel(app.RFFootPanel);
            app.XEditField_2Label.Text = 'X';
            app.RFFX = uieditfield(app.RFFootPanel, 'numeric');
            app.ZEditField_2Label = uilabel(app.RFFootPanel);
            app.ZEditField_2Label.Text = 'Z';
            app.RFFZ = uieditfield(app.RFFootPanel, 'numeric');
            app.YEditField_2Label = uilabel(app.RFFootPanel);
            app.YEditField_2Label.Text = 'Y';
            app.RFFY = uieditfield(app.RFFootPanel, 'numeric');
            app.LFFootPanel = uipanel(app.UIFigure);
            app.LFFootPanel.Title = 'Position of Left Front Foot';
            app.XEditField_3Label = uilabel(app.LFFootPanel);
            app.XEditField_3Label.Text = 'X';
            app.LFFX = uieditfield(app.LFFootPanel, 'numeric');
            app.ZEditField_3Label = uilabel(app.LFFootPanel);
            app.ZEditField_3Label.Text = 'Z';
            app.LFFZ = uieditfield(app.LFFootPanel, 'numeric');
            app.YEditField_3Label = uilabel(app.LFFootPanel);
            app.YEditField_3Label.Text = 'Y';
            app.LFFY = uieditfield(app.LFFootPanel, 'numeric');
            app.RBFootPanel = uipanel(app.UIFigure);
            app.RBFootPanel.Title = 'Position of Right Back Foot';
            app.XEditField_4Label = uilabel(app.RBFootPanel);
            app.XEditField_4Label.Text = 'X';
            app.RBFX = uieditfield(app.RBFootPanel, 'numeric');
            app.ZEditField_4Label = uilabel(app.RBFootPanel);
            app.ZEditField_4Label.Text = 'Z';
            app.RBFZ = uieditfield(app.RBFootPanel, 'numeric');
            app.YEditField_4Label = uilabel(app.RBFootPanel);
            app.YEditField_4Label.Text = 'Y';
            app.RBFY = uieditfield(app.RBFootPanel, 'numeric');
            app.LBFootPanel = uipanel(app.UIFigure);
            app.LBFootPanel.Title = 'Position of Left Back Foot';
            app.XEditField_5Label = uilabel(app.LBFootPanel);
            app.XEditField_5Label.Text = 'X';
            app.LBFX = uieditfield(app.LBFootPanel, 'numeric');
            app.ZEditField_5Label = uilabel(app.LBFootPanel);
            app.ZEditField_5Label.Text = 'Z';
            app.LBFZ = uieditfield(app.LBFootPanel, 'numeric');
            app.YEditField_5Label = uilabel(app.LBFootPanel);
            app.YEditField_5Label.Text = 'Y';
            app.LBFY = uieditfield(app.LBFootPanel, 'numeric');
            app.RobotPhysicalParameterSetPanel = uipanel(app.UIFigure);
            app.RobotPhysicalParameterSetPanel.Title = 'Robot Physical Parameter Set';
            app.BodyLengthEditFieldLabel = uilabel(app.RobotPhysicalParameterSetPanel);
            app.BodyLengthEditFieldLabel.Text = 'Body Length';
            app.BodyLength = uieditfield(app.RobotPhysicalParameterSetPanel, 'numeric');
            app.BodyLength.ValueChangedFcn = createCallbackFcn(app, @BodyLengthValueChanged, true);
            app.BodyLength.Value = 300;
            app.BodyWidthEditFieldLabel = uilabel(app.RobotPhysicalParameterSetPanel);
            app.BodyWidthEditFieldLabel.Text = 'Body Width';
            app.BodyWidth = uieditfield(app.RobotPhysicalParameterSetPanel, 'numeric');
            app.BodyWidth.ValueChangedFcn = createCallbackFcn(app, @BodyWidthValueChanged, true);
            app.BodyWidth.Value = 200;
            app.FLegSLinkLabel = uilabel(app.RobotPhysicalParameterSetPanel);
            app.FLegSLinkLabel.Text = 'F Leg S Link';
            app.FLegSLink = uieditfield(app.RobotPhysicalParameterSetPanel, 'numeric');
            app.FLegSLink.ValueChangedFcn = createCallbackFcn(app, @FLegSLinkValueChanged, true);
            app.FLegSLink.Value = 100;
            app.FLegULink1EditFieldLabel = uilabel(app.RobotPhysicalParameterSetPanel);
            app.FLegULink1EditFieldLabel.Text = 'F Leg U Link1';
            app.FLegULink1 = uieditfield(app.RobotPhysicalParameterSetPanel, 'numeric');
            app.FLegULink1.ValueChangedFcn = createCallbackFcn(app, @FLegULink1ValueChanged, true);
            app.FLegULink1.Value = 100;
            app.FLegULink2EditFieldLabel = uilabel(app.RobotPhysicalParameterSetPanel);
            app.FLegULink2EditFieldLabel.Text = 'F Leg U Link2';
            app.FLegULink2 = uieditfield(app.RobotPhysicalParameterSetPanel, 'numeric');
            app.FLegULink2.ValueChangedFcn = createCallbackFcn(app, @FLegULink2ValueChanged, true);
            app.FLegULink2.Value = 200;
            app.FLegLLinkEditFieldLabel = uilabel(app.RobotPhysicalParameterSetPanel);
            app.FLegLLinkEditFieldLabel.Text = 'F Leg L Link';
            app.FLegLLink = uieditfield(app.RobotPhysicalParameterSetPanel, 'numeric');
            app.FLegLLink.ValueChangedFcn = createCallbackFcn(app, @FLegLLinkValueChanged, true);
            app.FLegLLink.Value = 200;
            app.BLegSLinkEditFieldLabel = uilabel(app.RobotPhysicalParameterSetPanel);
            app.BLegSLinkEditFieldLabel.Text = 'B Leg S Link';
            app.BLegSLink = uieditfield(app.RobotPhysicalParameterSetPanel, 'numeric');
            app.BLegSLink.ValueChangedFcn = createCallbackFcn(app, @BLegSLinkValueChanged, true);
            app.BLegSLink.Value = 100;
            app.BLegULink1EditFieldLabel = uilabel(app.RobotPhysicalParameterSetPanel);
            app.BLegULink1EditFieldLabel.Text = 'B Leg U Link1';
            app.BLegULink1 = uieditfield(app.RobotPhysicalParameterSetPanel, 'numeric');
            app.BLegULink1.ValueChangedFcn = createCallbackFcn(app, @BLegULink1ValueChanged, true);
            app.BLegULink1.Value = 100;
            app.BLegULink2EditFieldLabel = uilabel(app.RobotPhysicalParameterSetPanel);
            app.BLegULink2EditFieldLabel.Text = 'B Leg U Link2';
            app.BLegULink2 = uieditfield(app.RobotPhysicalParameterSetPanel, 'numeric');
            app.BLegULink2.ValueChangedFcn = createCallbackFcn(app, @BLegULink2ValueChanged, true);
            app.BLegULink2.Value = 200;
            app.BLegLLinkEditFieldLabel = uilabel(app.RobotPhysicalParameterSetPanel);
            app.BLegLLinkEditFieldLabel.Text = 'B Leg L Link';
            app.BLegLLink = uieditfield(app.RobotPhysicalParameterSetPanel, 'numeric');
            app.BLegLLink.ValueChangedFcn = createCallbackFcn(app, @BLegLLinkValueChanged, true);
            app.BLegLLink.Value = 200;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NonWaistQuadrupedRobotFKUIforFast

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end